classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Login        matlab.ui.Figure
        Button       matlab.ui.control.Button
        Password     matlab.ui.control.NumericEditField
        Label_2      matlab.ui.control.Label
        Account      matlab.ui.control.NumericEditField
        Label        matlab.ui.control.Label
        COMSOL_Icon  matlab.ui.control.Image
        background   matlab.ui.control.Label
        Image        matlab.ui.control.Image
    end

    
    properties (Access = public)
        password % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            account=app.Account.Value;
            app.password=app.Password.Value;
            if account==1219196913 && app.password==100200300
                delete(app.Login);
                %open('D:\Softwares\COMSOL56\Multiphysics\bin\win64\comsolmphserver.exe')
                run(Calculate.mlapp);
            else
                errordlg('账号或密码错误','Error','modal');
            end
        end

        % Value changed function: Password
        function PasswordValueChanged(app, event)
            value = app.Password.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Login = uifigure('Visible', 'off');
            app.Login.Name = 'MATLAB App';
            app.Image = uiimage(app.Login);
            app.Image.ImageSource = 'Snipaste_2021-09-18_10-57-54.png';
            app.background = uilabel(app.Login);
            app.background.Text = '';
            app.COMSOL_Icon = uiimage(app.Login);
            app.COMSOL_Icon.ImageSource = 'comsol.jpg';
            app.Label = uilabel(app.Login);
            app.Label.Tooltip = {'请输入账号'};
            app.Label.Text = '账号';
            app.Account = uieditfield(app.Login, 'numeric');
            app.Label_2 = uilabel(app.Login);
            app.Label_2.Tooltip = {'请输入密码'};
            app.Label_2.Text = '密码';
            app.Password = uieditfield(app.Login, 'numeric');
            app.Password.ValueChangedFcn = createCallbackFcn(app, @PasswordValueChanged, true);
            app.Button = uibutton(app.Login, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '登录';
            % Show the figure after all components are created
            app.Login.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Login)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Login)
        end
    end
end